classdef taskSelectApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TaskSelectionUIFigure         matlab.ui.Figure
        TaskDescriptionTextAreaLabel  matlab.ui.control.Label
        TaskDescriptionTextArea       matlab.ui.control.TextArea
        SimulinkDroneFrameworkTaskSelectionLabel  matlab.ui.control.Label
        SelectanewtaskbyclickingoneofthebuttonsbelowLabel  matlab.ui.control.Label
        TaskSelectionListBoxLabel     matlab.ui.control.Label
        TaskSelectionListBox          matlab.ui.control.ListBox
        ActivateSelectionButton       matlab.ui.control.Button
        CurrentTaskLabel              matlab.ui.control.Label
        NoActiveTaskLabel             matlab.ui.control.Label
    end

%  Copyright 2018 The MathWorks, Inc.

    properties (Access = private)
        current_task = 'None' % Describes the current task.  on bootup, no task is assumed 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(findall(groot,'Tag','EnvSelTag'))
                %continue loading app
                app.TaskSelectionUIFigure.Tag = 'EnvSelTag';
                
                sel_task = convertListBoxTextToEnum(app.TaskSelectionListBox.Value);
                [app.TaskDescriptionTextArea.Value, disable] = taskDescriptionCallback(sel_task);
                
                if disable
                    % Turn off the button
                    app.ActivateSelectionButton.Enable = 'off';
                else
                    app.ActivateSelectionButton.Enable = 'on';
                end
                
            else
                % stop this app from opening.
                delete(app); 
            end
        end

        % Button pushed function: ActivateSelectionButton
        function ActivateSelectionButtonPushed(app, event)
            abort = false;
            
            new_task = app.TaskSelectionListBox.Value;
            
            if ~isequal(new_task, app.current_task)
                if ~isequal(app.current_task, 'None')
                    existing_task = convertListBoxTextToEnum(app.current_task);
                    abort = taskShutdownCallback(existing_task);
                else
                    % Do not influence the current task when app starts up.                   
                end
                
                if ~abort
                    % Second, start up the new task
                    app.current_task = new_task;
                    sel_task = convertListBoxTextToEnum(app.current_task);
                    base_workspace_set_up();
                    models_set_up(sel_task,true);
                                        
                    % Update the task text once the new task has been confirmed.
                    app.NoActiveTaskLabel.Text = app.current_task;
                    
                end % if ~abort
                
            end % ~isequal(new_task, app.current_task)
        end

        % Value changed function: TaskSelectionListBox
        function change_selected_task(app, event)

            sel_task = convertListBoxTextToEnum(app.TaskSelectionListBox.Value);
            [app.TaskDescriptionTextArea.Value, disable] = taskDescriptionCallback(sel_task);
            
            if disable
                % Turn off the button
                app.ActivateSelectionButton.Enable = 'off';
            else
                app.ActivateSelectionButton.Enable = 'on';
            end
            
        end

        % Close request function: TaskSelectionUIFigure
        function TaskSelectionUIFigureCloseRequest(app, event)
            
            if ~isequal(app.current_task, 'None')
                sel_task = convertListBoxTextToEnum(app.current_task);
                abort = taskShutdownCallback(sel_task);
            else
                abort = false;
            end
            if ~abort
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TaskSelectionUIFigure = uifigure('Visible', 'off');
            app.TaskSelectionUIFigure.Name = 'Task Selection';
            app.TaskSelectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @TaskSelectionUIFigureCloseRequest, true);
            app.TaskDescriptionTextAreaLabel = uilabel(app.TaskSelectionUIFigure);
            app.TaskDescriptionTextAreaLabel.Text = 'Task Description';
            app.TaskDescriptionTextArea = uitextarea(app.TaskSelectionUIFigure);
            app.TaskDescriptionTextArea.Editable = 'off';
            app.SimulinkDroneFrameworkTaskSelectionLabel = uilabel(app.TaskSelectionUIFigure);
            app.SimulinkDroneFrameworkTaskSelectionLabel.Text = 'Simulink Drone Framework Task Selection';
            app.SelectanewtaskbyclickingoneofthebuttonsbelowLabel = uilabel(app.TaskSelectionUIFigure);
            app.SelectanewtaskbyclickingoneofthebuttonsbelowLabel.Text = 'Select a new task by clicking one of the buttons below:';
            app.TaskSelectionListBoxLabel = uilabel(app.TaskSelectionUIFigure);
            app.TaskSelectionListBoxLabel.Text = 'Task Selection';
            app.TaskSelectionListBox = uilistbox(app.TaskSelectionUIFigure);
            app.TaskSelectionListBox.Items = {'Flight Controller Design', 'Test Flight Controller Under Different Conditions', 'Flight Envelope Characterization', 'UAV SME Capabilities Assessment', 'Test Correctness of the Autopilot’s Generated Code', 'Deploy and Test Correctness of the Autopilot’s Generated Code', 'System Integration Test', 'Run Regression Tests'};
            app.TaskSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @change_selected_task, true);
            app.TaskSelectionListBox.Value = 'Flight Controller Design';
            app.ActivateSelectionButton = uibutton(app.TaskSelectionUIFigure, 'push');
            app.ActivateSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateSelectionButtonPushed, true);
            app.ActivateSelectionButton.Text = 'Activate Selection';
            app.CurrentTaskLabel = uilabel(app.TaskSelectionUIFigure);
            app.CurrentTaskLabel.Text = 'Current Task: ';
            app.NoActiveTaskLabel = uilabel(app.TaskSelectionUIFigure);
            app.NoActiveTaskLabel.Text = 'No Active Task';
            % Show the figure after all components are created
            app.TaskSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = taskSelectApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TaskSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TaskSelectionUIFigure)
        end
    end
end